classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LEAFDISEASEDETETCTIONPanel  matlab.ui.container.Panel
        RESETButton                 matlab.ui.control.StateButton
        ORIGINALIMAGELabel          matlab.ui.control.Label
        IMAGESEGEMENTATIONButton    matlab.ui.control.StateButton
        IMAGECONTRASTButton         matlab.ui.control.StateButton
        SELECTButton                matlab.ui.control.StateButton
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
        img3 % Description
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SELECTButton
        function Select(app, event)
            %value = app.SELECTButton.Value;
            [file,path]=uigetfile('*.*');
            if isequal(file,0)
                figure(app.UIFigure);
                return;
            end
              figure(app.UIFigure);
            app.img=imread(fullfile(path,file));
            imshow(app.img,'Parent',app.UIAxes);
        end

        % Value changed function: IMAGECONTRASTButton
        function ImageContrast(app, event)
            %value = app.IMAGECONTRASTButton.Value
            app.img2=imadjust(app.img,stretchlim(app.img));
            imshow(app.img2,'Parent',app.UIAxes_2);
        end

        % Value changed function: IMAGESEGEMENTATIONButton
        function Imagesegementation(app, event)
            %value = app.IMAGESEGEMENTATIONButton.Value
             
             imshow(app.img3,'Parent',app.UIAxes_3);
        end

        % Value changed function: RESETButton
        function reset(app, event)
            %value = app.RESETButton.Value;
            app.UIAxes.cla;
            app.UIAxes_2.cla;
            app.UIAxes_3.cla;
            app.img="";
            app.img2="";
            app.img3="";
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEAFDISEASEDETETCTIONPanel = uipanel(app.UIFigure);
            app.LEAFDISEASEDETETCTIONPanel.Title = 'LEAF DISEASE DETETCTION';
            app.UIAxes = uiaxes(app.LEAFDISEASEDETETCTIONPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.LEAFDISEASEDETETCTIONPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.LEAFDISEASEDETETCTIONPanel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_3.Visible = 'off';
            app.SELECTButton = uibutton(app.LEAFDISEASEDETETCTIONPanel, 'state');
            app.SELECTButton.ValueChangedFcn = createCallbackFcn(app, @Select, true);
            app.SELECTButton.Text = 'SELECT';
            app.IMAGECONTRASTButton = uibutton(app.LEAFDISEASEDETETCTIONPanel, 'state');
            app.IMAGECONTRASTButton.ValueChangedFcn = createCallbackFcn(app, @ImageContrast, true);
            app.IMAGECONTRASTButton.Text = 'IMAGE CONTRAST';
            app.IMAGESEGEMENTATIONButton = uibutton(app.LEAFDISEASEDETETCTIONPanel, 'state');
            app.IMAGESEGEMENTATIONButton.ValueChangedFcn = createCallbackFcn(app, @Imagesegementation, true);
            app.IMAGESEGEMENTATIONButton.Text = 'IMAGE SEGEMENTATION';
            app.ORIGINALIMAGELabel = uilabel(app.LEAFDISEASEDETETCTIONPanel);
            app.ORIGINALIMAGELabel.Text = ' ORIGINAL IMAGE';
            app.RESETButton = uibutton(app.LEAFDISEASEDETETCTIONPanel, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @reset, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end